classdef Task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        Switch               matlab.ui.control.Switch
        SwitchLabel          matlab.ui.control.Label
        C2Spinner            matlab.ui.control.Spinner
        C2SpinnerLabel       matlab.ui.control.Label
        C1Spinner            matlab.ui.control.Spinner
        C1SpinnerLabel       matlab.ui.control.Label
        R3Spinner            matlab.ui.control.Spinner
        R3SpinnerLabel       matlab.ui.control.Label
        theta_aSpinner       matlab.ui.control.Spinner
        theta_aSpinnerLabel  matlab.ui.control.Label
        R2Spinner            matlab.ui.control.Spinner
        R2SpinnerLabel       matlab.ui.control.Label
        R1Spinner            matlab.ui.control.Spinner
        R1SpinnerLabel       matlab.ui.control.Label
        PlotButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            R1= app.R1Spinner.Value;
            R2= app.R2Spinner.Value;
            R3= app.R3Spinner.Value;
            C1= app.C1Spinner.Value;
            C2= app.C2Spinner.Value;
            theta_a= app.theta_aSpinner.Value;
            
            assignin('base','R1Spinner',app.R1Spinner);
            assignin('base','R2Spinner',app.R2Spinner);
            assignin('base','R3Spinner',app.R3Spinner);
            assignin('base','C1Spinner',app.C1Spinner);
            assignin('base','C2Spinner',app.C2Spinner);
            assignin('base','theta_aSpinner',app.theta_aSpinner);


            % Load the data
            sim Task2simulink.slx
            
            % Check theta_1,theta_2
            Q = string(app.Switch.Value);
            if Q == "theta_1"
            plot(app.UIAxes,simout.Time,simout.Data);
            else
          
            plot(app.UIAxes,simout1.Time,simout1.Data);
            end
            
            
        end

        % Callback function
        function SwitchValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph of Amplitude against Time')
            xlabel(app.UIAxes, 'Time (minutes)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.R1SpinnerLabel = uilabel(app.Panel);
            app.R1SpinnerLabel.Text = 'R1';
            app.R1Spinner = uispinner(app.Panel);
            app.R1Spinner.Value = 10;
            app.R2SpinnerLabel = uilabel(app.Panel);
            app.R2SpinnerLabel.Text = 'R2';
            app.R2Spinner = uispinner(app.Panel);
            app.R2Spinner.Value = 10;
            app.theta_aSpinnerLabel = uilabel(app.Panel);
            app.theta_aSpinnerLabel.Text = 'theta_a';
            app.theta_aSpinner = uispinner(app.Panel);
            app.theta_aSpinner.Value = 293.15;
            app.R3SpinnerLabel = uilabel(app.Panel);
            app.R3SpinnerLabel.Text = 'R3';
            app.R3Spinner = uispinner(app.Panel);
            app.R3Spinner.Value = 10;
            app.C1SpinnerLabel = uilabel(app.Panel);
            app.C1SpinnerLabel.Text = 'C1';
            app.C1Spinner = uispinner(app.Panel);
            app.C1Spinner.Value = 10;
            app.C2SpinnerLabel = uilabel(app.Panel);
            app.C2SpinnerLabel.Text = 'C2';
            app.C2Spinner = uispinner(app.Panel);
            app.C2Spinner.Value = 10;
            app.SwitchLabel = uilabel(app.Panel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.Items = {'theta_1', 'theta_2'};
            app.Switch.Value = 'theta_1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end